classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageUIFigure                  matlab.ui.Figure
        TimeSliderLabel                matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        RxmmLabel                      matlab.ui.control.Label
        RxmmEditField                  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        Load2DimagefromscriptButton    matlab.ui.control.Button
        LoadnewmodelButton             matlab.ui.control.Button
        RymmLabel                      matlab.ui.control.Label
        RymmEditField                  matlab.ui.control.NumericEditField
        XommEditFieldLabel             matlab.ui.control.Label
        XommEditField                  matlab.ui.control.NumericEditField
        YommEditFieldLabel             matlab.ui.control.Label
        YommEditField                  matlab.ui.control.NumericEditField
        ParametersofnewmodelLabel      matlab.ui.control.Label
        ZommEditFieldLabel             matlab.ui.control.Label
        ZommEditField                  matlab.ui.control.NumericEditField
        Refresh                        matlab.ui.control.Button
        CalculatemodelEnergyButton     matlab.ui.control.Button
        SegmenationButtonGroup         matlab.ui.container.ButtonGroup
        SingleframeButton              matlab.ui.control.RadioButton
        AllframesButton                matlab.ui.control.RadioButton
        StartSegmentationButton        matlab.ui.control.Button
        Information                    matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        TimeSlider_2Label              matlab.ui.control.Label
        TimeSlider_2                   matlab.ui.control.Slider
        ResultsLabel                   matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        ShowResultButton               matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        positiveEditFieldLabel         matlab.ui.control.Label
        positiveEditField              matlab.ui.control.NumericEditField
        negativeEditField_2Label       matlab.ui.control.Label
        negativeEditField              matlab.ui.control.NumericEditField
        maxnumberofiEditFieldLabel     matlab.ui.control.Label
        maxnumberofiEditField          matlab.ui.control.NumericEditField
        iwithoutreducingEEditField_2Label  matlab.ui.control.Label
        iwithoutreducingEEditField     matlab.ui.control.NumericEditField
        NeighbourhoodmmEditField_2Label  matlab.ui.control.Label
        NeighbourhoodmmEditField       matlab.ui.control.NumericEditField
        NodesnumberEditFieldLabel      matlab.ui.control.Label
        NodesnumberEditField           matlab.ui.control.NumericEditField
        ParametersofsegmentationLabel  matlab.ui.control.Label
        EditField_2Label               matlab.ui.control.Label
        alfa                           matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        beta                           matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        gamma                          matlab.ui.control.NumericEditField
        ReflectionDropDownLabel        matlab.ui.control.Label
        ReflectionDropDown             matlab.ui.control.DropDown
        SaveResultButton               matlab.ui.control.Button
        FiltersizeEditFieldLabel       matlab.ui.control.Label
        FiltersizeEditField            matlab.ui.control.NumericEditField
        FilterButton                   matlab.ui.control.StateButton
        Load2DimagefromfileButton      matlab.ui.control.Button
    end

    properties (Access = public)
        SegImage Image % Image that was selected by user
        SegModel Model 
        segResult 
        t 
        firstPhis
        value
    end
    
    methods (Access = private)
        
        function displayImage(app,t,z)
           % app.t = t;
            app.UIAxes.XLim = [0 app.SegImage.dim(Image.DIR_X)];
            app.UIAxes.YLim = [0 app.SegImage.dim(Image.DIR_Y)];
            mat = abs(squeeze(app.SegImage.voxels(:,:,z,t)));
            imagesc(mat,'Parent',app.UIAxes);
            daspect(app.UIAxes,[app.SegImage.voxelSize(Image.DIR_Y) app.SegImage.voxelSize(Image.DIR_X) 1]);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.SegImage.imageOrigin(Image.DIR_X), app.SegImage.imageOrigin(Image.DIR_Y),'r+', 'MarkerSize', 10);
            hold(app.UIAxes,'off');
        end
        function displayResult(app,t,z)
            % app.t = t;
            if strcmp(app.ReflectionDropDown.Value, 'no')
                app.UIAxes_2.XLim = [0 app.SegImage.dim(Image.DIR_X)];
                app.UIAxes_2.YLim = [0 app.SegImage.dim(Image.DIR_Y)];
            else
                app.UIAxes_2.XLim = [0 app.SegImage.dimX0];
                app.UIAxes_2.YLim = [0 app.SegImage.dimY0];
            end
            mat = abs(squeeze(app.segResult(t,z,:,:)));
            imagesc(mat,'Parent',app.UIAxes_2);
            daspect(app.UIAxes_2,[app.SegImage.voxelSize(Image.DIR_Y) app.SegImage.voxelSize(Image.DIR_X) 1]);
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,app.SegImage.imageOrigin(Image.DIR_X), app.SegImage.imageOrigin(Image.DIR_Y),'r+', 'MarkerSize', 10);
            hold(app.UIAxes_2,'off');
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.SegImage = Image(1,1,1,1);
            app.XommEditField.Enable = 'off';
            app.YommEditField.Enable = 'off';
            app.RxmmEditField.Enable = 'off';
            app.RymmEditField.Enable = 'off';
            app.NodesnumberEditField.Enable = 'off';
            app.EditField.Enable = 'off';
            app.positiveEditField.Enable = 'off';
            app.negativeEditField.Enable = 'off';
            app.maxnumberofiEditField.Enable = 'off';
            app.iwithoutreducingEEditField.Enable = 'off';
            app.ZommEditField.Enable = 'off';
            app.NeighbourhoodmmEditField.Enable = 'off';
            app.alfa.Enable = 'off';
            app.beta.Enable = 'off';
            app.gamma.Enable = 'off';
            app.UIAxes_2.Visible = 'off';
            app.FiltersizeEditField.Enable = 'off';
            app.TimeSlider.Visible = 'off';
            app.TimeSlider_2.Visible = 'off';
            app.TimeSliderLabel.Visible = 'off';
            app.TimeSlider_2Label.Visible = 'off';
            app.UIAxes_2.Colormap = gray(256);
        end

        % Button pushed function: Load2DimagefromscriptButton
        function Load2DimagefromscriptButtonPushed(app, event)
            [fileName,filePath] = uigetfile('*.m','Select an image file');
            if isequal(fileName, 0)
                uialert(app.ImageUIFigure, 'You pressed on Cancel. Choose a script!' , 'Image Error');
                return
            else
                uialert(app.ImageUIFigure, 'The script was selected. Wait for the image to load.','Information','Icon', 'info');
                app.SegImage = read2DImageFromScript(app.SegImage,filePath,fileName,app.FilterButton.Value, app.FiltersizeEditField.Value);
                if app.SegImage.dim(Image.DIR_T) > 1
                    app.TimeSlider.Visible = 'on';
                    app.TimeSlider_2.Visible = 'on';
                    app.TimeSliderLabel.Visible = 'on';
                    app.TimeSlider_2Label.Visible = 'on';
                end
                ReflectionDropDownValueChanged(app, 'ValueChanged');
                app.t = 1;
                %displayReflectedImage(app,app.t,1)
                displayImage(app,app.t,1);
                uialert(app.ImageUIFigure, 'The data has been loaded.','Information','Icon', 'info')
                app.TimeSlider.Limits = [0 app.SegImage.dim(Image.DIR_T)];
                app.TimeSlider.MajorTicks = (0:20:app.SegImage.dim(Image.DIR_T));
                app.XommEditField.Enable = 'on';
                app.YommEditField.Enable = 'on';
                app.RxmmEditField.Enable = 'on';
                app.RymmEditField.Enable = 'on';
                app.ZommEditField.Enable = 'on';
                app.NodesnumberEditField.Enable = 'on';
                app.NeighbourhoodmmEditField.Enable = 'on';
                if app.SegImage.imageOrigin(Image.DIR_Z) == 1
                    app.ZommEditField.Enable = 'off';
                end
                app.TimeSlider_2.Limits = [0 app.SegImage.dim(Image.DIR_T)];
                app.TimeSlider_2.MajorTicks = (0:20:app.SegImage.dim(Image.DIR_T));
                %ReflectedImage = ImageReflection(app.SegImage);
            end
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            tc = event.Value;
            app.t = round(tc);
            if app.t<1
                app.t=1;
            end
            displayImage(app,app.t,1);
        end

        % Button pushed function: LoadnewmodelButton
        function LoadnewmodelButtonPushed(app, event)
            displayImage(app,app.t,1);
            if app.RxmmEditField.Value == 0 || app.RymmEditField.Value == 0
                uialert(app.ImageUIFigure, 'R value cannot be 0' , 'Image Error');
                return
            end
            time = 1;
            if app.TimeSlider.Value>0
                time = app.TimeSlider.Value;
            end
            [xunit, yunit, app.SegModel, app.firstPhis] = create2DModelBasedOnElipse(app.SegModel, app.SegImage, app.XommEditField.Value, app.YommEditField.Value, app.ZommEditField.Value,app.RxmmEditField.Value, app.RymmEditField.Value,1, app.NodesnumberEditField.Value, app.NeighbourhoodmmEditField.Value,time);
            hold(app.UIAxes);
            plot(app.UIAxes, xunit, yunit);
            app.EditField.Enable = 'on';
            app.positiveEditField.Enable = 'on';
            app.negativeEditField.Enable = 'on';
            app.maxnumberofiEditField.Enable = 'on';
            app.iwithoutreducingEEditField.Enable = 'on';
            app.alfa.Enable = 'on';
            app.beta.Enable = 'on';
            app.gamma.Enable = 'on';
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            app.XommEditField.Value = 0;
            app.YommEditField.Value = 0;
            LoadnewmodelButtonPushed(app);
        end

        % Button pushed function: CalculatemodelEnergyButton
        function CalculatemodelEnergyButtonPushed(app, event)
            app.SegModel = setWeights(app.SegModel, app.alfa.Value, app.beta.Value, app.gamma.Value);           
            energyOfModel(app.SegModel,app.SegModel.rs);
        end

        % Button pushed function: Information
        function InformationButtonPushed(app, event)
            information1 = 2; %infomation about Image
            uialert(app.ImageUIFigure, information1 );
        end

        % Button pushed function: StartSegmentationButton
        function StartSegmentationButtonPushed(app, event)
            app.SegModel = setWeights(app.SegModel, app.alfa.Value, app.beta.Value, app.gamma.Value);
            if app.AllframesButton.Value == 1 
                app.SegModel = runFramesSegmentation(app.SegModel,app.maxnumberofiEditField.Value,app.iwithoutreducingEEditField.Value,app.EditField.Value,app.positiveEditField.Value,app.negativeEditField.Value,1,length(app.SegImage.time));
            else
                app.SegModel = runFramesSegmentation(app.SegModel,app.maxnumberofiEditField.Value,app.iwithoutreducingEEditField.Value,app.EditField.Value,app.positiveEditField.Value,app.negativeEditField.Value,app.t,app.t);
            end
            app.segResult = app.SegModel.binaryImage;
            displayResult(app,app.t,1)
            
            xunit = (app.RxmmEditField.Value * cos(app.firstPhis) / app.SegImage.voxelSize(app.SegImage.DIR_X)) + app.SegModel.middle(app.SegImage.DIR_X);
            yunit = (app.RymmEditField.Value * sin(app.firstPhis) / app.SegImage.voxelSize(app.SegImage.DIR_Y)) + app.SegModel.middle(app.SegImage.DIR_Y);
            hold(app.UIAxes_2);
            plot(app.UIAxes_2, xunit, yunit);
        end

        % Value changing function: TimeSlider_2
        function TimeSlider_2ValueChanging(app, event)
            tc = event.Value;
            app.t = round(tc);
            if app.t<1
                app.t=1;
            end
            displayResult(app,app.t,1);       
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
      %      app.segResult = getModelImage(app.SegModel);
            if strcmp(app.ReflectionDropDown.Value, 'down')
                app.segResult = getModelImageDown(app.SegModel);
            elseif strcmp(app.ReflectionDropDown.Value, 'right')
                app.segResult = getModelImageRight(app.SegModel);
            elseif strcmp(app.ReflectionDropDown.Value, 'up')
                app.segResult = getModelImageUp(app.SegModel);
            elseif strcmp(app.ReflectionDropDown.Value, 'left')
                app.segResult = getModelImageLeft(app.SegModel);
            else
                app.segResult = getModelImage(app.SegModel);
            end
            displayResult(app,app.t,1)
        end

        % Selection changed function: SegmenationButtonGroup
        function SegmenationButtonGroupSelectionChanged(app, event)
            selectedButton = app.SegmenationButtonGroup.SelectedObject;    
        end

        % Value changed function: ReflectionDropDown
        function ReflectionDropDownValueChanged(app, event)
            app.value = app.ReflectionDropDown.Value;
        if strcmp(app.value, 'no')
        elseif strcmp(app.value, 'up')
            app.SegImage = ImageReflectionUp(app.SegImage);
        elseif strcmp(app.value, 'down')
            app.SegImage = ImageReflectionDown(app.SegImage);
        elseif strcmp(app.value, 'left')
            app.SegImage = ImageReflectionLeft(app.SegImage);
        else
            app.SegImage = ImageReflectionRight(app.SegImage);
        end
            
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            saveResult(app.SegModel, app.segResult);
        end

        % Value changed function: FilterButton
        function FilterButtonValueChanged(app, event)
             if app.FilterButton.Value == 1
                app.FiltersizeEditField.Enable = 'on';
             else
                app.FiltersizeEditField.Enable = 'off';
             end
        end

        % Button pushed function: Load2DimagefromfileButton
        function Load2DimagefromfileButtonPushed(app, event)
            [fileName,filePath] = uigetfile('*.mat','Select an image file');
            if isequal(fileName, 0)
                uialert(app.ImageUIFigure, 'You pressed on Cancel. Choose a script!' , 'Image Error');
                return
            else
                uialert(app.ImageUIFigure, 'The script was selected. Wait for the image to load.','Information','Icon', 'info');
                app.SegImage = read2DImageFromFile(app.SegImage,filePath,fileName,app.FilterButton.Value, app.FiltersizeEditField.Value);
                if app.SegImage.dim(Image.DIR_T) > 1
                    app.TimeSlider.Visible = 'on';
                    app.TimeSlider_2.Visible = 'on';
                    app.TimeSliderLabel.Visible = 'on';
                    app.TimeSlider_2Label.Visible = 'on';
                end
                ReflectionDropDownValueChanged(app, 'ValueChanged');
                app.t = 1;
                %displayReflectedImage(app,app.t,1)
                displayImage(app,app.t,1);
                uialert(app.ImageUIFigure, 'The data has been loaded.','Information','Icon', 'info')
                app.TimeSlider.Limits = [0 app.SegImage.dim(Image.DIR_T)];
                app.TimeSlider.MajorTicks = (0:20:app.SegImage.dim(Image.DIR_T));
                app.XommEditField.Enable = 'on';
                app.YommEditField.Enable = 'on';
                app.RxmmEditField.Enable = 'on';
                app.RymmEditField.Enable = 'on';
                app.ZommEditField.Enable = 'on';
                app.NodesnumberEditField.Enable = 'on';
                app.NeighbourhoodmmEditField.Enable = 'on';
                if app.SegImage.imageOrigin(Image.DIR_Z) == 1
                    app.ZommEditField.Enable = 'off';
                end
                app.TimeSlider_2.Limits = [0 app.SegImage.dim(Image.DIR_T)];
                app.TimeSlider_2.MajorTicks = (0:20:app.SegImage.dim(Image.DIR_T));
                %ReflectedImage = ImageReflection(app.SegImage);
            end    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageUIFigure = uifigure;
            app.ImageUIFigure.Name = 'Image';
            app.TimeSliderLabel = uilabel(app.ImageUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.ImageUIFigure);
            app.TimeSlider.MajorTicks = [0 20 40 60 80 100];
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.RxmmLabel = uilabel(app.ImageUIFigure);
            app.RxmmLabel.Text = 'Rx [mm]';
            app.RxmmEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.RxmmEditField.Limits = [0 300];
            app.UIAxes = uiaxes(app.ImageUIFigure);
            title(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.Load2DimagefromscriptButton = uibutton(app.ImageUIFigure, 'push');
            app.Load2DimagefromscriptButton.ButtonPushedFcn = createCallbackFcn(app, @Load2DimagefromscriptButtonPushed, true);
            app.Load2DimagefromscriptButton.Text = 'Load 2D image from script';
            app.LoadnewmodelButton = uibutton(app.ImageUIFigure, 'push');
            app.LoadnewmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnewmodelButtonPushed, true);
            app.LoadnewmodelButton.Text = 'Load new model';
            app.RymmLabel = uilabel(app.ImageUIFigure);
            app.RymmLabel.Text = 'Ry [mm]';
            app.RymmEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.RymmEditField.Limits = [0 300];
            app.XommEditFieldLabel = uilabel(app.ImageUIFigure);
            app.XommEditFieldLabel.Text = 'Xo [mm]';
            app.XommEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.XommEditField.Limits = [-200 200];
            app.YommEditFieldLabel = uilabel(app.ImageUIFigure);
            app.YommEditFieldLabel.Text = 'Yo [mm]';
            app.YommEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.YommEditField.Limits = [-200 200];
            app.ParametersofnewmodelLabel = uilabel(app.ImageUIFigure);
            app.ParametersofnewmodelLabel.Text = 'Parameters of new model';
            app.ZommEditFieldLabel = uilabel(app.ImageUIFigure);
            app.ZommEditFieldLabel.Text = 'Zo [mm]';
            app.ZommEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.ZommEditField.Limits = [0 1000];
            app.Refresh = uibutton(app.ImageUIFigure, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Icon = 'Refresh.png';
            app.Refresh.Text = '';
            app.CalculatemodelEnergyButton = uibutton(app.ImageUIFigure, 'push');
            app.CalculatemodelEnergyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatemodelEnergyButtonPushed, true);
            app.CalculatemodelEnergyButton.Text = 'Calculate model Energy';
            app.SegmenationButtonGroup = uibuttongroup(app.ImageUIFigure);
            app.SegmenationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmenationButtonGroupSelectionChanged, true);
            app.SegmenationButtonGroup.Title = 'Segmenation';
            app.SingleframeButton = uiradiobutton(app.SegmenationButtonGroup);
            app.SingleframeButton.Text = 'Single frame';
            app.SingleframeButton.Value = true;
            app.AllframesButton = uiradiobutton(app.SegmenationButtonGroup);
            app.AllframesButton.Text = 'All frames';
            app.StartSegmentationButton = uibutton(app.ImageUIFigure, 'push');
            app.StartSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentationButtonPushed, true);
            app.StartSegmentationButton.Text = 'Start Segmentation';
            app.Information = uibutton(app.ImageUIFigure, 'push');
            app.Information.ButtonPushedFcn = createCallbackFcn(app, @InformationButtonPushed, true);
            app.Information.Icon = 'info-512.png';
            app.Information.Text = '';
            app.UIAxes_2 = uiaxes(app.ImageUIFigure);
            title(app.UIAxes_2, '')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = {'[ ]'};
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = {'[ ]'};
            app.TimeSlider_2Label = uilabel(app.ImageUIFigure);
            app.TimeSlider_2Label.Text = 'Time';
            app.TimeSlider_2 = uislider(app.ImageUIFigure);
            app.TimeSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.TimeSlider_2.ValueChangingFcn = createCallbackFcn(app, @TimeSlider_2ValueChanging, true);
            app.ResultsLabel = uilabel(app.ImageUIFigure);
            app.ResultsLabel.Text = 'Results';
            app.OriginalImageLabel = uilabel(app.ImageUIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.ShowResultButton = uibutton(app.ImageUIFigure, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Text = 'Show Result';
            app.Label_2 = uilabel(app.ImageUIFigure);
            app.Label_2.Text = 'λ';
            app.EditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.EditField.Limits = [0.01 10];
            app.EditField.Value = 1;
            app.positiveEditFieldLabel = uilabel(app.ImageUIFigure);
            app.positiveEditFieldLabel.Text = 'positive λ';
            app.positiveEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.positiveEditField.Limits = [0.01 10];
            app.positiveEditField.Value = 1.1;
            app.negativeEditField_2Label = uilabel(app.ImageUIFigure);
            app.negativeEditField_2Label.Text = 'negative λ';
            app.negativeEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.negativeEditField.Limits = [0.01 10];
            app.negativeEditField.Value = 1.1;
            app.maxnumberofiEditFieldLabel = uilabel(app.ImageUIFigure);
            app.maxnumberofiEditFieldLabel.Text = 'max number of i';
            app.maxnumberofiEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.maxnumberofiEditField.Limits = [1 1000];
            app.maxnumberofiEditField.Value = 100;
            app.iwithoutreducingEEditField_2Label = uilabel(app.ImageUIFigure);
            app.iwithoutreducingEEditField_2Label.Text = 'i without reducing E ';
            app.iwithoutreducingEEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.iwithoutreducingEEditField.Limits = [1 100];
            app.iwithoutreducingEEditField.Value = 5;
            app.NeighbourhoodmmEditField_2Label = uilabel(app.ImageUIFigure);
            app.NeighbourhoodmmEditField_2Label.Text = 'Neighbourhood [mm]';
            app.NeighbourhoodmmEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.NodesnumberEditFieldLabel = uilabel(app.ImageUIFigure);
            app.NodesnumberEditFieldLabel.Text = 'Nodes number';
            app.NodesnumberEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.NodesnumberEditField.Limits = [1 150];
            app.NodesnumberEditField.Value = 1;
            app.ParametersofsegmentationLabel = uilabel(app.ImageUIFigure);
            app.ParametersofsegmentationLabel.Text = 'Parameters of segmentation';
            app.EditField_2Label = uilabel(app.ImageUIFigure);
            app.EditField_2Label.Text = 'α';
            app.alfa = uieditfield(app.ImageUIFigure, 'numeric');
            app.alfa.Limits = [0 1];
            app.alfa.Value = 1;
            app.EditField_3Label = uilabel(app.ImageUIFigure);
            app.EditField_3Label.Text = ' β';
            app.beta = uieditfield(app.ImageUIFigure, 'numeric');
            app.beta.Limits = [0 1];
            app.beta.Value = 1;
            app.Label_3 = uilabel(app.ImageUIFigure);
            app.Label_3.Text = 'γ';
            app.gamma = uieditfield(app.ImageUIFigure, 'numeric');
            app.gamma.Limits = [0 1];
            app.gamma.Value = 1;
            app.ReflectionDropDownLabel = uilabel(app.ImageUIFigure);
            app.ReflectionDropDownLabel.Text = 'Reflection';
            app.ReflectionDropDown = uidropdown(app.ImageUIFigure);
            app.ReflectionDropDown.Items = {'no', 'up', 'down', 'left', 'right'};
            app.ReflectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ReflectionDropDownValueChanged, true);
            app.ReflectionDropDown.Value = 'no';
            app.SaveResultButton = uibutton(app.ImageUIFigure, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.FiltersizeEditFieldLabel = uilabel(app.ImageUIFigure);
            app.FiltersizeEditFieldLabel.Text = 'Filter size';
            app.FiltersizeEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.FiltersizeEditField.Limits = [1 10];
            app.FiltersizeEditField.RoundFractionalValues = 'on';
            app.FiltersizeEditField.Value = 1;
            app.FilterButton = uibutton(app.ImageUIFigure, 'state');
            app.FilterButton.ValueChangedFcn = createCallbackFcn(app, @FilterButtonValueChanged, true);
            app.FilterButton.Text = 'Filter';
            app.Load2DimagefromfileButton = uibutton(app.ImageUIFigure, 'push');
            app.Load2DimagefromfileButton.ButtonPushedFcn = createCallbackFcn(app, @Load2DimagefromfileButtonPushed, true);
            app.Load2DimagefromfileButton.Text = 'Load 2D image from file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageUIFigure)
        end
    end
end